classdef Drone_Tracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Switch                         matlab.ui.control.RockerSwitch
        SwitchLabel                    matlab.ui.control.Label
        Spiral_2_CAEditField           matlab.ui.control.NumericEditField
        Spiral_2_CAEditFieldLabel      matlab.ui.control.Label
        CA_2_SpiralEditField           matlab.ui.control.NumericEditField
        CA_2_SpiralEditFieldLabel      matlab.ui.control.Label
        Spiral_2_SpiralEditField       matlab.ui.control.NumericEditField
        Spiral_2_SpiralEditFieldLabel  matlab.ui.control.Label
        CA_2_CAEditField               matlab.ui.control.NumericEditField
        CA_2_CAEditFieldLabel          matlab.ui.control.Label
        OmegaEditField                 matlab.ui.control.NumericEditField
        OmegaEditFieldLabel            matlab.ui.control.Label
        VelEditField                   matlab.ui.control.NumericEditField
        VelEditFieldLabel              matlab.ui.control.Label
        thetaEditField                 matlab.ui.control.NumericEditField
        thetaEditFieldLabel            matlab.ui.control.Label
        Spiral_Pos_ZEditField          matlab.ui.control.NumericEditField
        Spiral_Pos_ZEditFieldLabel     matlab.ui.control.Label
        Spiral_Pos_YEditField          matlab.ui.control.NumericEditField
        Spiral_Pos_YEditFieldLabel     matlab.ui.control.Label
        Spiral_Pos_XEditField          matlab.ui.control.NumericEditField
        Spiral_Pos_XEditFieldLabel     matlab.ui.control.Label
        CA_Acc_ZEditField              matlab.ui.control.NumericEditField
        CA_Acc_ZEditFieldLabel         matlab.ui.control.Label
        CA_Acc_YEditField              matlab.ui.control.NumericEditField
        CA_Acc_YEditFieldLabel         matlab.ui.control.Label
        CA_Acc_XEditField              matlab.ui.control.NumericEditField
        CA_Acc_XEditFieldLabel         matlab.ui.control.Label
        CA_Vel_ZEditField              matlab.ui.control.NumericEditField
        CA_Vel_ZEditFieldLabel         matlab.ui.control.Label
        CA_Vel_YEditField              matlab.ui.control.NumericEditField
        CA_Vel_YEditFieldLabel         matlab.ui.control.Label
        CA_Vel_XEditField              matlab.ui.control.NumericEditField
        CA_Vel_XEditFieldLabel         matlab.ui.control.Label
        CA_Pos_ZEditField              matlab.ui.control.NumericEditField
        CA_Pos_ZEditFieldLabel         matlab.ui.control.Label
        CA_Pos_YEditField              matlab.ui.control.NumericEditField
        CA_Pos_YEditFieldLabel         matlab.ui.control.Label
        CA_Pos_XEditField              matlab.ui.control.NumericEditField
        CA_Pos_XEditFieldLabel         matlab.ui.control.Label
        TPMLabel                       matlab.ui.control.Label
        Spiral_Q_ElementsLabel         matlab.ui.control.Label
        CA_Q_ElementsLabel             matlab.ui.control.Label
        ResultsAnalysisButton          matlab.ui.control.Button
        CreateTrajectoryButton         matlab.ui.control.Button
        Knob                           matlab.ui.control.DiscreteKnob
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data=zeros(30000,3); % Description
       Default_KF= [
                        1.6929e-06, 0,          0,          0,       0,       0,       0,          0,          0;
                        0,          1.1685e-06, 0,          0,       0,       0,       0,          0,          0;
                        0,          0,          5.7749e-06, 0,       0,       0,       0,          0,          0;
                        0,          0,          0,          0.1491,  0,       0,       0,          0,          0;
                        0,          0,          0,          0,       0.1514,  0,       0,          0,          0;
                        0,          0,          0,          0,       0,       0.0422,  0,          0,          0;
                        0,          0,          0,          0,       0,       0,       4.7873e-06, 0,          0;
                        0,          0,          0,          0,       0,       0,       0,          3.5685e-05, 0;
                        0,          0,          0,          0,       0,       0,       0,          0,          2.4633e-07
                    ];


     Default_UKF = [
                4.5835e-06, 0,          0,          0,       0,       0,       0,          0,          0;
                0,          6.2005e-06, 0,          0,       0,       0,       0,          0,          0;
                0,          0,          1.1438e-05, 0,       0,       0,       0,          0,          0;
                0,          0,          0,          0.1438,  0,       0,       0,          0,          0;
                0,          0,          0,          0,       0.1891,  0,       0,          0,          0;
                0,          0,          0,          0,       0,       0.0598,  0,          0,          0;
                0,          0,          0,          0,       0,       0,       3.4419e-06, 0,          0;
                0,          0,          0,          0,       0,       0,       0,          2.5747e-06, 0;
                0,          0,          0,          0,       0,       0,       0,          0,          7.5969e-06
                 ];

       
     Default_ekf_kf = [     0.3809,      0,          0,           0,           0,           0;
                            0,           0.5186,     0,           0,           0,           0;
                            0,           0,          0.0576,      0,           0,           0;
                            0,           0,          0,           4.7967e-08,  0,           0;
                            0,           0,          0,           0,           1.2724e-08,  0;
                            0,           0,          0,           0,           0,           6.3331e-08
                        ];

     
     Default_ekf = [
                    0.7863, 0,      0,      0,      0,      0;
                    0,      1.1935, 0,      0,      0,      0;
                    0,      0,      0.0507, 0,      0,      0;
                    0,      0,      0,      0.3131, 0,      0;
                    0,      0,      0,      0,      0.0340, 0;
                    0,      0,      0,      0,      0,      0.0400
                    ];

     
    Default_KF_EKF_TPM = [0.95 0.05;
                          0.45 0.55];
    

    end
    
    methods (Access = private)
        
        function Run_IMM_KF_Spiral_Analysis(app, QKF, QS, TPM)
 
            % ---------- 0) Defaults & data ----------
            T        = 0.01;     dt = T;
            radarSTD = 50;
            if isempty(app.Data) || size(app.Data,2)~=3
                error('app.Data must be Nx3 truth positions. Create a trajectory first.');
            end
            truth = double(app.Data);
            N     = size(truth,1);
            meas  = truth + radarSTD*randn(size(truth));
            R     = (radarSTD^2)*eye(3);
        
            if ~isequal(size(TPM),[2 2]) 
                error('TPM must be 2x2.'); end
        
            % ---------- 1) CA-KF ----------
            F1 = [1 0 0 dt 0 0 0.5*dt^2 0 0;
                  0 1 0 0 dt 0 0 0.5*dt^2 0;
                  0 0 1 0 0 dt 0 0 0.5*dt^2;
                  0 0 0 1 0 0 dt 0 0;
                  0 0 0 0 1 0 0 dt 0;
                  0 0 0 0 0 1 0 0 dt;
                  0 0 0 0 0 0 1 0 0;
                  0 0 0 0 0 0 0 1 0;
                  0 0 0 0 0 0 0 0 1];
            H1 = [eye(3), zeros(3,6)];
        
            Xkf = zeros(9,1);  Pkf = eye(9);
            estKF   = zeros(N,3);
            innovKF = zeros(N,3);
            errKF   = zeros(N,3);
        
            for k = 1:N
                z  = meas(k,:)';
                Xp = F1*Xkf;  Pp = F1*Pkf*F1' + QKF;
                v  = z - H1*Xp;
                S  = H1*Pp*H1' + R; S=(S+S')/2; [~,p]=chol(S,'lower');
                if p>0, S = S + (1e-9 + 1e-12*trace(S))*eye(3); end
                K  = (Pp*H1')/S;
                Xkf = Xp + K*v;
                I9  = eye(9);
                Pkf = (I9 - K*H1)*Pp*(I9 - K*H1)' + K*R*K';
                estKF(k,:)   = Xkf(1:3)';
                innovKF(k,:) = v';
                errKF(k,:)   = truth(k,:) - estKF(k,:);
            end
        
            % ---------- 2) Spiral-EKF ----------
            Xs = zeros(6,1);  Ps = eye(6);
            estSP   = zeros(N,3);
            innovSP = zeros(N,3);
            errSP   = zeros(N,3);
        
            for k = 1:N
                z = meas(k,:)';
                th = Xs(4); v = Xs(5); w = Xs(6); eps_w = 1e-5;
        
                if abs(w) < eps_w
                    Xp = [Xs(1)+v*cos(th)*dt; Xs(2)+v*sin(th)*dt; Xs(3)+v*dt; th; v; w];
                    F = [1 0 0 -v*sin(th)*dt  cos(th)*dt 0;
                         0 1 0  v*cos(th)*dt  sin(th)*dt 0;
                         0 0 1  0             dt        0;
                         0 0 0  1             0         dt;
                         0 0 0  0             1         0;
                         0 0 0  0             0         1];
                else
                    dth = th + w*dt; c1=cos(dth)-cos(th); s1=sin(dth)-sin(th);
                    Xp = [Xs(1)+v/w*c1; Xs(2)+v/w*s1; Xs(3)+v*dt; dth; v; w];
                    F  = eye(6);
                    F(1,4)= v/w*s1;            F(1,5)= c1/w;            F(1,6)= v/w^2*(w*dt*sin(dth)-s1);
                    F(2,4)=-v/w*c1;            F(2,5)= s1/w;            F(2,6)= v/w^2*(-w*dt*cos(dth)-c1);
                    F(3,5)= dt;                F(4,6)= dt;
                end
        
                Pp = F*Ps*F' + QS;
                Hs = [eye(3), zeros(3,3)];
                v  = z - Hs*Xp;
                S  = Hs*Pp*Hs' + R; S=(S+S')/2; [~,p]=chol(S,'lower');
                if p>0, S = S + (1e-9 + 1e-12*trace(S))*eye(3); end
                K  = (Pp*Hs')/S; I6 = eye(6);
                Xs = Xp + K*v;
                Ps = (I6 - K*Hs)*Pp*(I6 - K*Hs)' + K*R*K';
                estSP(k,:)   = Xs(1:3)';
                innovSP(k,:) = v';
                errSP(k,:)   = truth(k,:) - estSP(k,:);
            end
        
            % ---------- 3) IMM ----------
            mu = [0.5; 0.5];
            X1 = zeros(9,1);  P1 = eye(9);
            X2 = zeros(6,1);  P2 = eye(6);
        
            estIMM   = zeros(N,3);
            innovIMM = zeros(N,3);
            errIMM   = zeros(N,3);
        
            for k = 1:N
                z = meas(k,:)';
        
                % CA-KF branch
                X1p = F1*X1; P1p = F1*P1*F1' + QKF;
                v1  = z - H1*X1p;  S1 = H1*P1p*H1' + R; S1=(S1+S1')/2; [~,p]=chol(S1,'lower');
                if p>0, S1 = S1 + (1e-9 + 1e-12*trace(S1))*eye(3); end
                K1  = (P1p*H1')/S1; I9=eye(9);
                X1  = X1p + K1*v1;  P1 = (I9 - K1*H1)*P1p*(I9 - K1*H1)' + K1*R*K1';
                x_ca = X1(1:3);
        
                % L1
                U = chol(S1,'lower'); logdetS1 = 2*sum(log(diag(U)));
                q1 = U'\(U\v1); L1 = exp(-0.5*(v1'*q1 + logdetS1 + 3*log(2*pi))); L1=max(L1,realmin);
        
                % Spiral branch
                th=X2(4); v=X2(5); w=X2(6); eps_w=1e-5;
                if abs(w) < eps_w
                    X2p=[X2(1)+v*cos(th)*dt; X2(2)+v*sin(th)*dt; X2(3)+v*dt; th; v; w];
                    F = [1 0 0 -v*sin(th)*dt  cos(th)*dt 0;
                         0 1 0  v*cos(th)*dt  sin(th)*dt 0;
                         0 0 1  0             dt        0;
                         0 0 0  1             0         dt;
                         0 0 0  0             1         0;
                         0 0 0  0             0         1];
                else
                    dth = th + w*dt; c1=cos(dth)-cos(th); s1=sin(dth)-sin(th);
                    X2p=[X2(1)+v/w*c1; X2(2)+v/w*s1; X2(3)+v*dt; dth; v; w];
                    F = eye(6);
                    F(1,4)= v/w*s1; F(1,5)= c1/w; F(1,6)= v/w^2*(w*dt*sin(dth)-s1);
                    F(2,4)=-v/w*c1; F(2,5)= s1/w; F(2,6)= v/w^2*(-w*dt*cos(dth)-c1);
                    F(3,5)= dt;     F(4,6)= dt;
                end
                P2p = F*P2*F' + QS; Hs=[eye(3),zeros(3,3)];
                v2  = z - Hs*X2p;  S2 = Hs*P2p*Hs' + R; S2=(S2+S2')/2; [~,p]=chol(S2,'lower');
                if p>0, S2 = S2 + (1e-9 + 1e-12*trace(S2))*eye(3); end
                K2  = (P2p*Hs')/S2; I6=eye(6);
                X2  = X2p + K2*v2;  P2 = (I6 - K2*Hs)*P2p*(I6 - K2*Hs)' + K2*R*K2';
                x_sp = X2(1:3);
        
                % L2
                U = chol(S2,'lower'); logdetS2 = 2*sum(log(diag(U)));
                q2 = U'\(U\v2); L2 = exp(-0.5*(v2'*q2 + logdetS2 + 3*log(2*pi))); L2=max(L2,realmin);
        
                % Mode update & fusion
                mu = (TPM.'*mu) .* [L1; L2]; mu = mu/sum(mu);
                x_imm = mu(1)*x_ca + mu(2)*x_sp;
        
                estIMM(k,:)   = x_imm';
                innovIMM(k,:) = (mu(1)*v1 + mu(2)*v2)';
                errIMM(k,:)   = truth(k,:) - x_imm';
            end
        
            % ---------- 4) Stats (after SKIP) ----------
            t = (0:N-1)'*T;
            SKIP = 3000;
            if N - SKIP < 10, SKIP = max(0, N-10); end   % keep some samples
            idx  = (SKIP+1):N;
        
            rm_kf = sqrt(mean(errKF(idx,:).^2,1));
            rm_sp = sqrt(mean(errSP(idx,:).^2,1));
            rm_im = sqrt(mean(errIMM(idx,:).^2,1));
            var_kf = var(errKF(idx,:),0,1);
            var_sp = var(errSP(idx,:),0,1);
            var_im = var(errIMM(idx,:),0,1);
        
            % ---------- 5) Plots ----------
            % 3D tracking (NO NOISE)
            figure('Name','3D Tracking — IMM','Color','w'); hold on; grid on; axis equal
            plot3(truth(:,1),truth(:,2),truth(:,3),'k-','LineWidth',1.4);
            plot3(estIMM(:,1),estIMM(:,2),estIMM(:,3),'--','LineWidth',1.2);
            legend('Truth','Estimate','Location','best'); xlabel X; ylabel Y; zlabel Z; view(3);
        
            figure('Name','3D Tracking — KF (CA)','Color','w'); hold on; grid on; axis equal
            plot3(truth(:,1),truth(:,2),truth(:,3),'k-','LineWidth',1.4);
            plot3(estKF(:,1),estKF(:,2),estKF(:,3),'--','LineWidth',1.2);
            legend('Truth','Estimate','Location','best'); xlabel X; ylabel Y; zlabel Z; view(3);
        
            figure('Name','3D Tracking — Spiral-EKF','Color','w'); hold on; grid on; axis equal
            plot3(truth(:,1),truth(:,2),truth(:,3),'k-','LineWidth',1.4);
            plot3(estSP(:,1),estSP(:,2),estSP(:,3),'--','LineWidth',1.2);
            legend('Truth','Estimate','Location','best'); xlabel X; ylabel Y; zlabel Z; view(3);
        
            % Axis tracking (full run, WITH measurements)
            labels = {'X[m]','Y[m]','Z[m]'};
            dataSets = { {'Axis Tracking — IMM', estIMM}, ...
                         {'Axis Tracking — Spiral-EKF', estSP}, ...
                         {'Axis Tracking — KF (CA)', estKF} };
            for ds = 1:numel(dataSets)
                figure('Name',dataSets{ds}{1},'Color','w');
                est = dataSets{ds}{2};
                for a = 1:3
                    subplot(3,1,a); hold on; grid on;
                    plot(truth(:,a),'k','LineWidth',2.5);
                    plot(est(:,a),'b--','LineWidth',2.5);
                    plot(meas(:,a),'.','MarkerSize',0.0001);
                    ylabel(sprintf('%s (m)', labels{a}));
                    if a==1, legend('Truth','Estimate','Meas','Location','best'); end
                    if a==3, xlabel('Step'); end
                end
            end
        
            % Innovations (after SKIP)
            innovSets = { {'Innovation — IMM', innovIMM}, ...
                          {'Innovation — Spiral-EKF', innovSP}, ...
                          {'Innovation — KF (CA)', innovKF} };
            for is = 1:numel(innovSets)
                figure('Name',[innovSets{is}{1} ' (after skip)'],'Color','w');
                invv = innovSets{is}{2};
                for a = 1:3
                    subplot(3,1,a); hold on; grid on;
                    plot(t(idx), invv(idx,a),'LineWidth',1.0);
                    ylabel(sprintf('\\nu_%s (m)', labels{a}));
                    if a==3, xlabel('Time (s)'); end
                end
            end
        
            % Raw |error| vs time (after SKIP)
            rawSets = { {'Raw |error| vs Time — IMM', abs(errIMM)}, ...
                        {'Raw |error| vs Time — Spiral-EKF', abs(errSP)}, ...
                        {'Raw |error| vs Time — KF (CA)', abs(errKF)} };
            for rs = 1:numel(rawSets)
                figure('Name',[rawSets{rs}{1} ' (after skip)'],'Color','w');
                rw = rawSets{rs}{2};
                for a = 1:3
                    subplot(3,1,a); hold on; grid on;
                    plot(t(idx), rw(idx,a),'LineWidth',1.1);
                    ylabel(sprintf('|e|_%s (m)', labels{a}));
                    if a==3, xlabel('Time (s)'); end
                end
            end
        
            % RMSE and VAR grouped bars (after SKIP)
            figure('Name','RMSE per Axis (m) — after skip','Color','w');
            M = [rm_im; rm_sp; rm_kf];
            bar(M,'grouped'); ylabel('RMSE (m)'); grid on; set(gca,'XTickLabel',{'IMM','Spiral','KF'});
            legend(labels,'Location','best'); title('RMSE per Axis (m) — after initial transient');
        
            figure('Name','Error Variance per Axis — after skip','Color','w');
            V = [var_im; var_sp; var_kf];
            bar(V,'grouped'); ylabel('Variance (m^2)'); grid on; set(gca,'XTickLabel',{'IMM','Spiral','KF'});
            legend(labels,'Location','best'); title('Error Variance per Axis — after initial transient');
        
            % ---------- 6) Console summary (after SKIP) ----------
            fprintf('Using SKIP = %d (out of N = %d)\n', SKIP, N);
            disp('--- RMSE (x y z) [m] — after skip ---');
            fprintf('IMM   : [%.3f %.3f %.3f]\n', rm_im);
            fprintf('Spiral: [%.3f %.3f %.3f]\n', rm_sp);
            fprintf('KF    : [%.3f %.3f %.3f]\n', rm_kf);
            disp('--- VAR(error) (x y z) [m^2] — after skip ---');
            fprintf('IMM   : [%.3f %.3f %.3f]\n', var_im);
            fprintf('Spiral: [%.3f %.3f %.3f]\n', var_sp);
            fprintf('KF    : [%.3f %.3f %.3f]\n', var_kf);
        end

                
        function Run_IMM_UKF_Spiral_Analysis(app, QCA, QS, TPM)
            T  = 0.01;  dt = T;
            radarSTD = 50;
            truth = double(app.Data);
            N     = size(truth,1);
            meas  = truth + radarSTD*randn(size(truth));
            R     = (radarSTD^2)*eye(3);
            F = [1 0 0 dt 0 0 0.5*dt^2 0 0;
                 0 1 0 0 dt 0 0 0.5*dt^2 0;
                 0 0 1 0 0 dt 0 0 0.5*dt^2;
                 0 0 0 1 0 0 dt 0 0;
                 0 0 0 0 1 0 0 dt 0;
                 0 0 0 0 0 1 0 0 dt;
                 0 0 0 0 0 0 1 0 0;
                 0 0 0 0 0 0 0 1 0;
                 0 0 0 0 0 0 0 0 1];
            H = [eye(3), zeros(3,6)];
        
            % UKF weights (standard)
            n = 9; alpha = 1e-1; beta = 2; kappa = 0;
            lambda = alpha^2*(n+kappa)-n;  c = n+lambda;
            Wm = [lambda/c; repmat(1/(2*c), 2*n, 1)];
            Wc = Wm; Wc(1) = Wc(1) + (1 - alpha^2 + beta);
        
            Xukf = zeros(n,1);  Pukf = eye(n);
            estUKF   = zeros(N,3);
            innovUKF = zeros(N,3);   % per-axis innovation (projected)
            errUKF   = zeros(N,3);
        
            for k=1:N
                % sigma points
                Ps = (Pukf+Pukf')/2;  [S,p] = chol(Ps,'lower'); if p>0, S = chol(Ps + 1e-9*eye(n),'lower'); end
                Xsig = [Xukf, repmat(Xukf,1,n)+sqrt(c)*S, repmat(Xukf,1,n)-sqrt(c)*S];
        
                % predict
                Xsig_pred = F * Xsig;
                Xp  = Xsig_pred * Wm;
                Pp  = QCA;
                for i=1:2*n+1
                    d = Xsig_pred(:,i) - Xp;
                    Pp = Pp + Wc(i)*(d*d');
                end
        
                % predict measurement
                Ysig = H * Xsig_pred;
                Yp   = Ysig * Wm;
                Pyy  = R; Pxy = zeros(n,3);
                for i=1:2*n+1
                    dy = Ysig(:,i) - Yp;
                    dx = Xsig_pred(:,i) - Xp;
                    Pyy = Pyy + Wc(i)*(dy*dy');
                    Pxy = Pxy + Wc(i)*(dx*dy');
                end
                Pyy = (Pyy+Pyy')/2; [Sy,p] = chol(Pyy,'lower');
                if p>0, Pyy = Pyy + (1e-9 + 1e-12*trace(Pyy))*eye(3); Sy = chol(Pyy,'lower'); end
        
                % update
                z  = meas(k,:)';
                nu = z - Yp;
                K  = Pxy / Pyy;
                Xukf = Xp + K*nu;
                Pukf = Pp - K*Pyy*K';
        
                estUKF(k,:)   = Xukf(1:3)';
                innovUKF(k,:) = nu';                   % store comp-wise innovation
                errUKF(k,:)   = truth(k,:) - estUKF(k,:);
            end
        
            % ---------- 2) Spiral-EKF branch alone ----------
            Xs = zeros(6,1); Ps = eye(6);
            estSP   = zeros(N,3);
            innovSP = zeros(N,3);
            errSP   = zeros(N,3);
        
            for k=1:N
                z = meas(k,:)';
                th = Xs(4); v = Xs(5); w = Xs(6); eps_w = 1e-5;
        
                if abs(w) < eps_w
                    Xp = [Xs(1)+v*cos(th)*dt; Xs(2)+v*sin(th)*dt; Xs(3)+v*dt; th; v; w];
                    Fs = [1 0 0 -v*sin(th)*dt  cos(th)*dt 0;
                          0 1 0  v*cos(th)*dt  sin(th)*dt 0;
                          0 0 1  0             dt        0;
                          0 0 0  1             0         dt;
                          0 0 0  0             1         0;
                          0 0 0  0             0         1];
                else
                    dth = th + w*dt; c1=cos(dth)-cos(th); s1=sin(dth)-sin(th);
                    Xp = [Xs(1)+v/w*c1; Xs(2)+v/w*s1; Xs(3)+v*dt; dth; v; w];
                    Fs = eye(6);
                    Fs(1,4)= v/w*s1;            Fs(1,5)= c1/w;            Fs(1,6)= v/w^2*(w*dt*sin(dth)-s1);
                    Fs(2,4)=-v/w*c1;            Fs(2,5)= s1/w;            Fs(2,6)= v/w^2*(-w*dt*cos(dth)-c1);
                    Fs(3,5)= dt;                Fs(4,6)= dt;
                end
        
                Pp = Fs*Ps*Fs' + QS;
                Hs = [eye(3), zeros(3,3)];
                v  = z - Hs*Xp;
                S  = Hs*Pp*Hs' + R; S=(S+S')/2; [~,p]=chol(S,'lower');
                if p>0, S = S + (1e-9 + 1e-12*trace(S))*eye(3); end
                K  = (Pp*Hs')/S; I6 = eye(6);
                Xs = Xp + K*v;  Ps = (I6 - K*Hs)*Pp*(I6 - K*Hs)' + K*R*K';
        
                estSP(k,:)   = Xs(1:3)';
                innovSP(k,:) = v';
                errSP(k,:)   = truth(k,:) - estSP(k,:);
            end
        
            % ---------- 3) IMM fusion (UKF + Spiral-EKF) ----------
            mu = [0.5; 0.5];
            % Initialize branch states/covs for IMM run
            X1 = zeros(9,1); P1 = eye(9);   % CA-UKF branch
            X2 = zeros(6,1); P2 = eye(6);   % Spiral-EKF branch
        
            estIMM   = zeros(N,3);
            innovIMM = zeros(N,3);
            errIMM   = zeros(N,3);
        
            for k=1:N
                z = meas(k,:)';
        
                % ----- Branch 1: CA-UKF step (same math as above, but using X1,P1) -----
                Ps = (P1+P1')/2; [S1c,p] = chol(Ps,'lower'); if p>0, S1c = chol(Ps+1e-9*eye(n),'lower'); end
                Xsig = [X1, repmat(X1,1,n)+sqrt(c)*S1c, repmat(X1,1,n)-sqrt(c)*S1c];
        
                Xsig_pred = F * Xsig;
                Xp = Xsig_pred * Wm;
                Pp = QCA;
                for i=1:2*n+1
                    d = Xsig_pred(:,i) - Xp;
                    Pp = Pp + Wc(i)*(d*d');
                end
        
                Ysig = H * Xsig_pred;
                Yp   = Ysig * Wm;
                Pyy  = R; Pxy = zeros(n,3);
                for i=1:2*n+1
                    dy = Ysig(:,i) - Yp;
                    dx = Xsig_pred(:,i) - Xp;
                    Pyy = Pyy + Wc(i)*(dy*dy');
                    Pxy = Pxy + Wc(i)*(dx*dy');
                end
                Pyy = (Pyy+Pyy')/2; [Sy,p] = chol(Pyy,'lower');
                if p>0, Pyy = Pyy + (1e-9 + 1e-12*trace(Pyy))*eye(3); Sy = chol(Pyy,'lower'); end
                v1 = z - Yp;
                K1 = Pxy / Pyy;
                X1 = Xp + K1*v1;
                P1 = Pp - K1*Pyy*K1';
                x_ca = X1(1:3);
        
                % Likelihood for branch 1
                logdetS1 = 2*sum(log(diag(Sy)));
                q1 = Sy'\(Sy\v1);
                L1 = exp(-0.5*(v1'*q1 + logdetS1 + 3*log(2*pi))); L1 = max(L1,realmin);
        
                % ----- Branch 2: Spiral-EKF step (predict/update) -----
                th=X2(4); v=X2(5); w=X2(6); eps_w=1e-5;
                if abs(w) < eps_w
                    X2p=[X2(1)+v*cos(th)*dt; X2(2)+v*sin(th)*dt; X2(3)+v*dt; th; v; w];
                    Fs=[1 0 0 -v*sin(th)*dt  cos(th)*dt 0;
                        0 1 0  v*cos(th)*dt  sin(th)*dt 0;
                        0 0 1  0             dt        0;
                        0 0 0  1             0         dt;
                        0 0 0  0             1         0;
                        0 0 0  0             0         1];
                else
                    dth=th+w*dt; c1=cos(dth)-cos(th); s1=sin(dth)-sin(th);
                    X2p=[X2(1)+v/w*c1; X2(2)+v/w*s1; X2(3)+v*dt; dth; v; w];
                    Fs=eye(6);
                    Fs(1,4)= v/w*s1; Fs(1,5)= c1/w; Fs(1,6)= v/w^2*(w*dt*sin(dth)-s1);
                    Fs(2,4)=-v/w*c1; Fs(2,5)= s1/w; Fs(2,6)= v/w^2*(-w*dt*cos(dth)-c1);
                    Fs(3,5)= dt;     Fs(4,6)= dt;
                end
                P2p = Fs*P2*Fs' + QS; Hs=[eye(3),zeros(3,3)];
                v2  = z - Hs*X2p;  S2 = Hs*P2p*Hs' + R; S2=(S2+S2')/2; [S2c,p] = chol(S2,'lower');
                if p>0, S2 = S2 + (1e-9 + 1e-12*trace(S2))*eye(3); S2c = chol(S2,'lower'); end
                K2  = (P2p*Hs')/S2;
                X2  = X2p + K2*v2; I6=eye(6);
                P2  = (I6 - K2*Hs)*P2p*(I6 - K2*Hs)' + K2*R*K2';
                x_sp = X2(1:3);
        
                % Likelihood for branch 2
                logdetS2 = 2*sum(log(diag(S2c)));
                q2 = S2c'\(S2c\v2);
                L2 = exp(-0.5*(v2'*q2 + logdetS2 + 3*log(2*pi))); L2 = max(L2,realmin);
        
                % ----- Mode update & fusion -----
                mu = (TPM.' * mu) .* [L1; L2];
                mu = mu / sum(mu);
                x_imm = mu(1)*x_ca + mu(2)*x_sp;
        
                estIMM(k,:)   = x_imm';
                innovIMM(k,:) = (mu(1)*v1 + mu(2)*v2)';   % just for plotting
                errIMM(k,:)   = truth(k,:) - x_imm';
            end
        
            % ---------- 4) Stats (after SKIP) ----------
            t = (0:N-1)'*T;
            SKIP = 3000;  if N - SKIP < 10, SKIP = max(0,N-10); end
            idx  = (SKIP+1):N;
        
            rm_ukf = sqrt(mean(errUKF(idx,:).^2,1));
            rm_sp  = sqrt(mean(errSP(idx,:).^2,1));
            rm_im  = sqrt(mean(errIMM(idx,:).^2,1));
            var_ukf = var(errUKF(idx,:),0,1);
            var_sp  = var(errSP(idx,:),0,1);
            var_im  = var(errIMM(idx,:),0,1);
        
            % ---------- 5) Plots ----------
            % 3D (NO NOISE)
            figure('Name','3D Tracking — IMM','Color','w'); hold on; grid on; axis equal
            plot3(truth(:,1),truth(:,2),truth(:,3),'k-','LineWidth',1.4);
            plot3(estIMM(:,1),estIMM(:,2),estIMM(:,3),'--','LineWidth',1.2);
            legend('Truth','Estimate','Location','best'); xlabel X; ylabel Y; zlabel Z; view(3);
        
            figure('Name','3D Tracking — UKF (CA)','Color','w'); hold on; grid on; axis equal
            plot3(truth(:,1),truth(:,2),truth(:,3),'k-','LineWidth',1.4);
            plot3(estUKF(:,1),estUKF(:,2),estUKF(:,3),'--','LineWidth',1.2);
            legend('Truth','Estimate','Location','best'); xlabel X; ylabel Y; zlabel Z; view(3);
        
            figure('Name','3D Tracking — Spiral-EKF','Color','w'); hold on; grid on; axis equal
            plot3(truth(:,1),truth(:,2),truth(:,3),'k-','LineWidth',1.4);
            plot3(estSP(:,1),estSP(:,2),estSP(:,3),'--','LineWidth',1.2);
            legend('Truth','Estimate','Location','best'); xlabel X; ylabel Y; zlabel Z; view(3);
        
            % Axis tracking (full, WITH measurements)
            labels = {'X[m]','Y[m]','Z[m]'};
            dataSets = { {'Axis Tracking — IMM', estIMM}, ...
                         {'Axis Tracking — Spiral-EKF', estSP}, ...
                         {'Axis Tracking — UKF (CA)', estUKF} };
            for ds = 1:numel(dataSets)
                figure('Name',dataSets{ds}{1},'Color','w');
                est = dataSets{ds}{2};
                for a=1:3
                    subplot(3,1,a); hold on; grid on;
                    plot(truth(:,a),'k','LineWidth',2.5);
                    plot(est(:,a),'b--','LineWidth',2.5);
                    plot(meas(:,a),'.','MarkerSize',0.0001);
                    ylabel(sprintf('%s (m)', labels{a}));
                    if a==1, legend('Truth','Estimate','Meas','Location','best'); end
                    if a==3, xlabel('Step'); end
                end
            end
        
            % Innovations (after SKIP)
            innovSets = { {'Innovation — IMM', innovIMM}, ...
                          {'Innovation — Spiral-EKF', innovSP}, ...
                          {'Innovation — UKF (CA)', innovUKF} };
            for is = 1:numel(innovSets)
                figure('Name',[innovSets{is}{1} ' (after skip)'],'Color','w');
                invv = innovSets{is}{2};
                for a=1:3
                    subplot(3,1,a); hold on; grid on;
                    plot(t(idx), invv(idx,a),'LineWidth',1.0);
                    ylabel(sprintf('\\nu_%s (m)', labels{a})); if a==3, xlabel('Time (s)'); end
                end
            end
        
            % Raw |error| vs time (after SKIP)
            rawSets = { {'Raw |error| vs Time — IMM', abs(errIMM)}, ...
                        {'Raw |error| vs Time — Spiral-EKF', abs(errSP)}, ...
                        {'Raw |error| vs Time — UKF (CA)', abs(errUKF)} };
            for rs = 1:numel(rawSets)
                figure('Name',[rawSets{rs}{1} ' (after skip)'],'Color','w');
                rw = rawSets{rs}{2};
                for a=1:3
                    subplot(3,1,a); hold on; grid on;
                    plot(t(idx), rw(idx,a),'LineWidth',1.1);
                    ylabel(sprintf('|e|_%s (m)', labels{a})); if a==3, xlabel('Time (s)'); end
                end
            end
        
            % RMSE/VAR grouped bars (after SKIP)
            figure('Name','RMSE per Axis (m) — after skip','Color','w');
            M = [rm_im; rm_sp; rm_ukf];          % IMM, Spiral, UKF
            bar(M,'grouped'); ylabel('RMSE (m)'); grid on; set(gca,'XTickLabel',{'IMM','Spiral','UKF'});
            legend(labels,'Location','best'); title('RMSE per Axis (m) — after initial transient');
        
            figure('Name','Error Variance per Axis — after skip','Color','w');
            V = [var_im; var_sp; var_ukf];
            bar(V,'grouped'); ylabel('Variance (m^2)'); grid on; set(gca,'XTickLabel',{'IMM','Spiral','UKF'});
            legend(labels,'Location','best'); title('Error Variance per Axis — after initial transient');
        
            % ---------- 6) Console summary ----------
            fprintf('Using SKIP = %d (out of N = %d)\n', SKIP, N);
            disp('--- RMSE (x y z) [m] — after skip ---');
            fprintf('IMM   : [%.3f %.3f %.3f]\n', rm_im);
            fprintf('Spiral: [%.3f %.3f %.3f]\n', rm_sp);
            fprintf('UKF   : [%.3f %.3f %.3f]\n', rm_ukf);
            disp('--- VAR(error) (x y z) [m^2] — after skip ---');
            fprintf('IMM   : [%.3f %.3f %.3f]\n', var_im);
            fprintf('Spiral: [%.3f %.3f %.3f]\n', var_sp);
            fprintf('UKF   : [%.3f %.3f %.3f]\n', var_ukf);
        end

      function Run_CA_KF_Analysis(app, QCA)
            % ---------- defaults & data ----------
            T  = 0.01;  dt = T;
            radarSTD = 50;
            if isempty(app.Data) || size(app.Data,2)~=3
                error('app.Data must be Nx3 truth positions. Create a trajectory first.');
            end
            truth = double(app.Data);
            N     = size(truth,1);
        
            meas = truth + radarSTD*randn(size(truth));  % synthetic measurements
            R    = (radarSTD^2)*eye(3);
            % ---------- CA model ----------
            F = [1 0 0 dt 0 0 0.5*dt^2 0         0;
                 0 1 0 0  dt 0 0        0.5*dt^2 0;
                 0 0 1 0  0  dt 0       0        0.5*dt^2;
                 0 0 0 1  0  0  dt      0        0;
                 0 0 0 0  1  0  0       dt       0;
                 0 0 0 0  0  1  0       0        dt;
                 0 0 0 0  0  0  1       0        0;
                 0 0 0 0  0  0  0       1        0;
                 0 0 0 0  0  0  0       0        1];
            H = [eye(3), zeros(3,6)];
        
            % ---------- KF run ----------
            X = zeros(9,1);  P = eye(9);
            estKF   = zeros(N,3);
            innovKF = zeros(N,3);
            errKF   = zeros(N,3);
        
            for k = 1:N
                z = meas(k,:)';
        
                % Predict
                Xp = F*X;
                Pp = F*P*F' + QCA;
        
                % Innovation (SPD guard) 
                v = z - H*Xp;
                S = H*Pp*H' + R;
                S = (S+S')/2; [~,p] = chol(S,'lower');
                if p>0, S = S + (1e-9 + 1e-12*trace(S))*eye(3); end
        
                % Gain & Joseph update
                K = (Pp*H')/S;
                X = Xp + K*v;
                I9 = eye(9);
                P = (I9 - K*H)*Pp*(I9 - K*H)' + K*R*K';
        
                % Logs
                estKF(k,:)   = X(1:3)';
                innovKF(k,:) = v';
                errKF(k,:)   = truth(k,:) - estKF(k,:);
            end
        
            % ---------- stats (after SKIP) ----------
            t = (0:N-1)'*T;
            SKIP = 3000;                         % initial transient
            if N - SKIP < 10, SKIP = max(0,N-10); end
            idx = (SKIP+1):N;
        
            rm_kf  = sqrt(mean(errKF(idx,:).^2,1));
            var_kf = var(errKF(idx,:),0,1);
        
            % ---------- plots ----------
            % 3D tracking (NO noise)
            figure('Name','3D Tracking — KF (CA)','Color','w'); hold on; grid on; axis equal
            plot3(truth(:,1),truth(:,2),truth(:,3),'k-','LineWidth',1.4);
            plot3(estKF(:,1),estKF(:,2),estKF(:,3),'--','LineWidth',1.2);
            legend('Truth','Estimate','Location','best'); xlabel X; ylabel Y; zlabel Z; view(3);
        
            % Axis tracking (full run, with measurements)
            labels = {'X','Y','Z'};
            figure('Name','Axis Tracking — KF (CA)','Color','w');
            for a = 1:3
                subplot(3,1,a); hold on; grid on;
                plot(truth(:,a),'k','LineWidth',2.5);
                plot(estKF(:,a),'b--','LineWidth',2.5);
                plot(meas(:,a),'.','MarkerSize',0.0001);
                ylabel(sprintf('%s (m)', labels{a}));
                if a==1, legend('Truth','Estimate','Meas','Location','best'); end
                if a==3, xlabel('Step'); end
            end
        
            % Innovations (after SKIP)
            figure('Name','Innovation — KF (CA) (after skip)','Color','w');
            for a = 1:3
                subplot(3,1,a); hold on; grid on;
                plot(t(idx), innovKF(idx,a),'LineWidth',1.0);
                ylabel(sprintf('\\nu_%s (m)', labels{a})); if a==3, xlabel('Time (s)'); end
            end
        
            % Raw |error| vs time (after SKIP)
            figure('Name','Raw |error| vs Time — KF (CA) (after skip)','Color','w');
            absErr = abs(errKF);
            for a = 1:3
                subplot(3,1,a); hold on; grid on;
                plot(t(idx), absErr(idx,a),'LineWidth',1.1);
                ylabel(sprintf('|e|_%s (m)', labels{a})); if a==3, xlabel('Time (s)'); end
            end
        
            % RMSE & VAR grouped bars (after SKIP)
            figure('Name','RMSE per Axis (m) — KF (CA) after skip','Color','w');
            bar(rm_kf,'grouped'); ylabel('RMSE (m)'); grid on; set(gca,'XTickLabel',labels);
            title('RMSE per Axis (m) — after initial transient');
        
            figure('Name','Error Variance per Axis — KF (CA) after skip','Color','w');
            bar(var_kf,'grouped'); ylabel('Variance (m^2)'); grid on; set(gca,'XTickLabel',labels);
            title('Error Variance per Axis — after initial transient');
        
            % ---------- console summary ----------
            fprintf('Using SKIP = %d (out of N = %d)\n', SKIP, N);
            disp('--- KF (CA) — RMSE (x y z) [m] after skip ---');
            fprintf('[%.3f %.3f %.3f]\n', rm_kf);
            disp('--- KF (CA) — VAR(error) (x y z) [m^2] after skip ---');
            fprintf('[%.3f %.3f %.3f]\n', var_kf);
      end

      function RUN_CA_UKF_Analysis(app, QCA)        
            T  = 0.01;  dt = T;
            radarSTD = 50;
            if isempty(app.Data) || size(app.Data,2)~=3
                error('app.Data must be Nx3 truth positions. Create a trajectory first.');
            end
            truth = double(app.Data);
            N     = size(truth,1);
            meas = truth + radarSTD*randn(size(truth));  % synthetic measurements
            R    = (radarSTD^2)*eye(3);
            % ---------- CA model ----------
            F = [1 0 0 dt 0 0 0.5*dt^2 0         0;
                 0 1 0 0  dt 0 0        0.5*dt^2 0;
                 0 0 1 0  0  dt 0       0        0.5*dt^2;
                 0 0 0 1  0  0  dt      0        0;
                 0 0 0 0  1  0  0       dt       0;
                 0 0 0 0  0  1  0       0        dt;
                 0 0 0 0  0  0  1       0        0;
                 0 0 0 0  0  0  0       1        0;
                 0 0 0 0  0  0  0       0        1];
            H = [eye(3), zeros(3,6)];
        
            % ---------- UKF weights ----------
            n = 9; alpha = 1e-1; beta = 2; kappa = 0;
            lambda = alpha^2*(n+kappa)-n;  c = n+lambda;
            Wm = [lambda/c; repmat(1/(2*c), 2*n, 1)];
            Wc = Wm; Wc(1) = Wc(1) + (1 - alpha^2 + beta);
        
            % ---------- UKF run ----------
            X = zeros(n,1);  P = eye(n);
            estUKF   = zeros(N,3);
            innovUKF = zeros(N,3);    % component-wise innovations
            errUKF   = zeros(N,3);
        
            for k = 1:N
                % sigma points
                Ps = (P+P')/2; [S,p] = chol(Ps,'lower');
                if p>0, S = chol(Ps + 1e-9*eye(n),'lower'); end
                Xsig = [X, repmat(X,1,n)+sqrt(c)*S, repmat(X,1,n)-sqrt(c)*S];
        
                % predict
                Xsig_pred = F * Xsig;
                Xp = Xsig_pred * Wm;
                Pp = QCA;
                for i = 1:2*n+1
                    d = Xsig_pred(:,i) - Xp;
                    Pp = Pp + Wc(i)*(d*d');
                end
        
                % predict measurement
                Ysig = H * Xsig_pred;
                Yp   = Ysig * Wm;
                Pyy  = R; Pxy = zeros(n,3);
                for i = 1:2*n+1
                    dy = Ysig(:,i) - Yp;
                    dx = Xsig_pred(:,i) - Xp;
                    Pyy = Pyy + Wc(i)*(dy*dy');
                    Pxy = Pxy + Wc(i)*(dx*dy');
                end
                Pyy = (Pyy+Pyy')/2; [Sy,p] = chol(Pyy,'lower');
                if p>0, Pyy = Pyy + (1e-9 + 1e-12*trace(Pyy))*eye(3); Sy = chol(Pyy,'lower'); end
        
                % update
                z  = meas(k,:)';
                nu = z - Yp;
                K  = Pxy / Pyy;
                X  = Xp + K*nu;
                P  = Pp - K*Pyy*K';
        
                % logs
                estUKF(k,:)   = X(1:3)';
                innovUKF(k,:) = nu';
                errUKF(k,:)   = truth(k,:) - estUKF(k,:);
            end
        
            % ---------- stats (after SKIP) ----------
            t = (0:N-1)'*T;
            SKIP = 3000;  if N - SKIP < 10, SKIP = max(0,N-10); end
            idx = (SKIP+1):N;
        
            rm_ukf  = sqrt(mean(errUKF(idx,:).^2,1));
            var_ukf = var(errUKF(idx,:),0,1);
        
            % ---------- plots ----------
            % 3D tracking (NO noise)
            figure('Name','3D Tracking — UKF (CA)','Color','w'); hold on; grid on; axis equal
            plot3(truth(:,1),truth(:,2),truth(:,3),'k-','LineWidth',1.4);
            plot3(estUKF(:,1),estUKF(:,2),estUKF(:,3),'--','LineWidth',1.2);
            legend('Truth','Estimate','Location','best'); xlabel X; ylabel Y; zlabel Z; view(3);
        
            % Axis tracking (full run, WITH measurements)
            labels = {'X','Y','Z'};
            figure('Name','Axis Tracking — UKF (CA)','Color','w');
            for a = 1:3
                subplot(3,1,a); hold on; grid on;
                plot(truth(:,a),'k','LineWidth',2.5);
                plot(estUKF(:,a),'b--','LineWidth',2.5);
                plot(meas(:,a),'.','MarkerSize',0.0001);
                ylabel(sprintf('%s (m)', labels{a}));
                if a==1, legend('Truth','Estimate','Meas','Location','best'); end
                if a==3, xlabel('Step'); end
            end
        
            % Innovations (after SKIP)
            figure('Name','Innovation — UKF (CA) (after skip)','Color','w');
            for a = 1:3
                subplot(3,1,a); hold on; grid on;
                plot(t(idx), innovUKF(idx,a),'LineWidth',1.0);
                ylabel(sprintf('\\nu_%s (m)', labels{a})); if a==3, xlabel('Time (s)'); end
            end
        
            % Raw |error| vs time (after SKIP)
            figure('Name','Raw |error| vs Time — UKF (CA) (after skip)','Color','w');
            absErr = abs(errUKF);
            for a = 1:3
                subplot(3,1,a); hold on; grid on;
                plot(t(idx), absErr(idx,a),'LineWidth',1.1);
                ylabel(sprintf('|e|_%s (m)', labels{a})); if a==3, xlabel('Time (s)'); end
            end
        
            % RMSE & VAR grouped bars (after SKIP)
            figure('Name','RMSE per Axis (m) — UKF (CA) after skip','Color','w');
            bar(rm_ukf,'grouped'); ylabel('RMSE (m)'); grid on; set(gca,'XTickLabel',labels);
            title('RMSE per Axis (m) — after initial transient');
        
            figure('Name','Error Variance per Axis — UKF (CA) after skip','Color','w');
            bar(var_ukf,'grouped'); ylabel('Variance (m^2)'); grid on; set(gca,'XTickLabel',labels);
            title('Error Variance per Axis — after initial transient');
        
            % ---------- console summary ----------
            fprintf('Using SKIP = %d (out of N = %d)\n', SKIP, N);
            disp('--- UKF (CA) — RMSE (x y z) [m] after skip ---');
            fprintf('[%.3f %.3f %.3f]\n', rm_ukf);
            disp('--- UKF (CA) — VAR(error) (x y z) [m^2] after skip ---');
            fprintf('[%.3f %.3f %.3f]\n', var_ukf);
      end

    function RUN_Spiral_EKF_Analysis(app, Qs)
        T  = 0.01;  dt = T;
        radarSTD = 50;
        if isempty(app.Data) || size(app.Data,2)~=3
            error('app.Data must be Nx3 truth positions. Create a trajectory first.');
        end
        truth = double(app.Data);
        N     = size(truth,1);
        meas = truth + radarSTD*randn(size(truth));  % synthetic measurements
        R    = (radarSTD^2)*eye(3);
        % ---------- EKF run ----------
        % State: [x y z th v w]'
        X = zeros(6,1);  P = eye(6);
        estSP   = zeros(N,3);
        innovSP = zeros(N,3);
        errSP   = zeros(N,3);
    
        for k = 1:N
            z = meas(k,:)';
    
            % ---- Predict (spiral/turn model) ----
            th = X(4); v = X(5); w = X(6);
            eps_w = 1e-5;
    
            if abs(w) < eps_w
                % straight-line limit
                Xp = [X(1)+v*cos(th)*dt;
                      X(2)+v*sin(th)*dt;
                      X(3)+v*dt;
                      th;
                      v;
                      w];
                F = [1 0 0 -v*sin(th)*dt  cos(th)*dt 0;
                     0 1 0  v*cos(th)*dt  sin(th)*dt 0;
                     0 0 1  0             dt        0;
                     0 0 0  1             0         dt;
                     0 0 0  0             1         0;
                     0 0 0  0             0         1];
            else
                % constant turn-rate in x-y, linear in z
                dth = th + w*dt; c1 = cos(dth)-cos(th); s1 = sin(dth)-sin(th);
                Xp = [X(1)+v/w*c1;
                      X(2)+v/w*s1;
                      X(3)+v*dt;
                      dth;
                      v;
                      w];
                F = eye(6);
                F(1,4)= v/w*s1;            F(1,5)= c1/w;            F(1,6)= v/w^2*(w*dt*sin(dth)-s1);
                F(2,4)=-v/w*c1;            F(2,5)= s1/w;            F(2,6)= v/w^2*(-w*dt*cos(dth)-c1);
                F(3,5)= dt;                F(4,6)= dt;
            end
    
            Pp = F*P*F' + Qs;
    
            % ---- Update (positions measured) ----
            Hs = [eye(3), zeros(3,3)];
            v  = z - Hs*Xp;                              % innovation
            S  = Hs*Pp*Hs' + R;
            S  = (S+S')/2; [~,p] = chol(S,'lower');      % SPD guard
            if p>0, S = S + (1e-9 + 1e-12*trace(S))*eye(3); end
            K  = (Pp*Hs')/S;
    
            % Joseph-form covariance update
            X = Xp + K*v;
            I6 = eye(6);
            P = (I6 - K*Hs)*Pp*(I6 - K*Hs)' + K*R*K';
    
            % Logs
            estSP(k,:)   = X(1:3)';
            innovSP(k,:) = v';
            errSP(k,:)   = truth(k,:) - estSP(k,:);
        end
    
        % ---------- stats (after SKIP) ----------
        t = (0:N-1)'*T;
        SKIP = 3000;  if N - SKIP < 10, SKIP = max(0,N-10); end
        idx = (SKIP+1):N;
    
        rm_sp  = sqrt(mean(errSP(idx,:).^2,1));
        var_sp = var(errSP(idx,:),0,1);
    
        % ---------- plots ----------
        % 3D tracking (NO noise)
        figure('Name','3D Tracking — Spiral-EKF','Color','w'); hold on; grid on; axis equal
        plot3(truth(:,1),truth(:,2),truth(:,3),'k-','LineWidth',1.4);
        plot3(estSP(:,1),estSP(:,2),estSP(:,3),'--','LineWidth',1.2);
        legend('Truth','Estimate','Location','best'); xlabel X; ylabel Y; zlabel Z; view(3);
    
        % Axis tracking (full run, WITH measurements)
        labels = {'X','Y','Z'};
        figure('Name','Axis Tracking — Spiral-EKF','Color','w');
        for a = 1:3
            subplot(3,1,a); hold on; grid on;
            plot(truth(:,a),'k','LineWidth',2.5);
            plot(estSP(:,a),'b--','LineWidth',2.5);
            plot(meas(:,a),'.','MarkerSize',0.0001);
            ylabel(sprintf('%s (m)', labels{a}));
            if a==1, legend('Truth','Estimate','Meas','Location','best'); end
            if a==3, xlabel('Step'); end
        end
    
        % Innovations (after SKIP)
        figure('Name','Innovation — Spiral-EKF (after skip)','Color','w');
        for a = 1:3
            subplot(3,1,a); hold on; grid on;
            plot(t(idx), innovSP(idx,a),'LineWidth',1.0);
            ylabel(sprintf('\\nu_%s (m)', labels{a})); if a==3, xlabel('Time (s)'); end
        end
    
        % Raw |error| vs time (after SKIP)
        figure('Name','Raw |error| vs Time — Spiral-EKF (after skip)','Color','w');
        absErr = abs(errSP);
        for a = 1:3
            subplot(3,1,a); hold on; grid on;
            plot(t(idx), absErr(idx,a),'LineWidth',1.1);
            ylabel(sprintf('|e|_%s (m)', labels{a})); if a==3, xlabel('Time (s)'); end
        end
    
        % RMSE & VAR grouped bars (after SKIP)
        figure('Name','RMSE per Axis (m) — Spiral-EKF after skip','Color','w');
        bar(rm_sp,'grouped'); ylabel('RMSE (m)'); grid on; set(gca,'XTickLabel',labels);
        title('RMSE per Axis (m) — after initial transient');
    
        figure('Name','Error Variance per Axis — Spiral-EKF after skip','Color','w');
        bar(var_sp,'grouped'); ylabel('Variance (m^2)'); grid on; set(gca,'XTickLabel',labels);
        title('Error Variance per Axis — after initial transient');
    
        % ---------- console summary ----------
        fprintf('Using SKIP = %d (out of N = %d)\n', SKIP, N);
        disp('--- Spiral-EKF — RMSE (x y z) [m] after skip ---');
        fprintf('[%.3f %.3f %.3f]\n', rm_sp);
        disp('--- Spiral-EKF — VAR(error) (x y z) [m^2] after skip ---');
        fprintf('[%.3f %.3f %.3f]\n', var_sp);
    end


 end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateTrajectoryButton
        function Create_traj(app, event)
            rng('shuffle'); dt=0.01; dur=300; N=dur/dt;
            box=[-1200 1200; -1200 1200; 40 700];
            t_wp=sort([0 rand(1,randi([10 20]))*dur dur]); wp=zeros(numel(t_wp),3);
            for i=1:3, wp(:,i)=box(i,1)+diff(box(i,:))*rand(size(t_wp)); end
            t=linspace(0,dur,N)'; ref=zeros(N,3);
            for i=1:3, ref(:,i)=interp1(t_wp,wp(:,i),t,'pchip'); end
            v_max=25; a_max=8; j_max=50;
            vel=zeros(N,3); acc=zeros(N,3); traj=zeros(N,3); traj(1,:)=ref(1,:);
            for k=2:N
                v_des=(ref(k,:)-ref(k-1,:))/dt;
                if norm(v_des)>v_max, v_des=v_des*v_max/norm(v_des); end
                a_des=(v_des-vel(k-1,:))/dt;
                if norm(a_des)>a_max, a_des=a_des*a_max/norm(a_des); end
                jerk=(a_des-acc(k-1,:))/dt;
                if norm(jerk)>j_max, jerk=jerk*j_max/norm(jerk); a_des=acc(k-1,:)+jerk*dt; end
                acc(k,:)=a_des; vel(k,:)=vel(k-1,:)+a_des*dt; traj(k,:)=traj(k-1,:)+vel(k,:)*dt;
            end
            for i=1:3, traj(:,i)=min(max(traj(:,i),box(i,1)),box(i,2)); end
            app.Data=traj;
            plot3(app.UIAxes,app.Data(:,1),app.Data(:,2),app.Data(:,3));
        end

        % Value changed function: Knob
        function Select_model(app, event)
            value = app.Knob.Value;
            switch(value)
                case("Constant Accelaration")
                    app.CA_Acc_XEditField.Visible="on ";
                    app.CA_Acc_XEditFieldLabel.Visible="on";
                    app.CA_Acc_YEditField.Visible="on";
                    app.CA_Acc_YEditFieldLabel.Visible="on";
                    app.CA_Acc_ZEditField.Visible="on";
                    app.CA_Acc_ZEditFieldLabel.Visible="on";
                    app.CA_Pos_XEditField.Visible="on";
                    app.CA_Pos_XEditFieldLabel.Visible="on";
                    app.CA_Pos_YEditField.Visible="on";
                    app.CA_Pos_YEditFieldLabel.Visible="on";
                    app.CA_Pos_ZEditField.Visible="on";
                    app.CA_Pos_ZEditFieldLabel.Visible="on";
                    app.CA_Q_ElementsLabel.Visible="on";
                    app.CA_Vel_XEditField.Visible="on";
                    app.CA_Vel_XEditFieldLabel.Visible="on";
                    app.CA_Vel_YEditField.Visible="on";
                    app.CA_Vel_YEditFieldLabel.Visible="on";
                    app.CA_Vel_ZEditField.Visible="on";
                    app.CA_Vel_ZEditFieldLabel.Visible="on";
                    app.Switch.Visible="on";
                    app.SwitchLabel.Visible="on";
                    app.Spiral_Q_ElementsLabel.Visible="off";
                    app.Spiral_Pos_XEditField.Visible="off";
                    app.Spiral_Pos_XEditFieldLabel.Visible="off";
                    app.Spiral_Pos_YEditField.Visible="off";
                    app.Spiral_Pos_YEditFieldLabel.Visible="off";
                    app.Spiral_Pos_ZEditField.Visible="off";
                    app.Spiral_Pos_ZEditFieldLabel.Visible="off";
                    app.thetaEditField.Visible="off";
                    app.thetaEditFieldLabel.Visible="off";
                    app.VelEditField.Visible="off";
                    app.VelEditFieldLabel.Visible="off";
                    app.OmegaEditField.Visible="off";
                    app.OmegaEditFieldLabel.Visible="off";
                    app.CA_2_CAEditField.Visible="off";
                    app.CA_2_CAEditFieldLabel.Visible="off";
                    app.CA_2_SpiralEditField.Visible="off";
                    app.CA_2_SpiralEditFieldLabel.Visible="off";
                    app.Spiral_2_CAEditField.Visible="off";
                    app.Spiral_2_CAEditFieldLabel.Visible="off";
                    app.Spiral_2_SpiralEditField.Visible="off";
                    app.Spiral_2_SpiralEditFieldLabel.Visible="off";
                    app.TPMLabel.Visible="off";
                case("Spiral")
                    app.Spiral_Q_ElementsLabel.Visible="on";
                    app.Spiral_Pos_XEditField.Visible="on";
                    app.Spiral_Pos_XEditFieldLabel.Visible="on";
                    app.Spiral_Pos_YEditField.Visible="on";
                    app.Spiral_Pos_YEditFieldLabel.Visible="on";
                    app.Spiral_Pos_ZEditField.Visible="on";
                    app.Spiral_Pos_ZEditFieldLabel.Visible="on";
                    app.thetaEditField.Visible="on";
                    app.thetaEditFieldLabel.Visible="on";
                    app.VelEditField.Visible="on";
                    app.VelEditFieldLabel.Visible="on";
                    app.OmegaEditField.Visible="on";
                    app.OmegaEditFieldLabel.Visible="on";
                    app.CA_2_CAEditField.Visible="off";
                    app.CA_2_CAEditFieldLabel.Visible="off";
                    app.CA_2_SpiralEditField.Visible="off";
                    app.CA_2_SpiralEditFieldLabel.Visible="off";
                    app.Spiral_2_CAEditField.Visible="off";
                    app.Spiral_2_CAEditFieldLabel.Visible="off";
                    app.Spiral_2_SpiralEditField.Visible="off";
                    app.Spiral_2_SpiralEditFieldLabel.Visible="off";
                    app.TPMLabel.Visible="off";
                    app.CA_Acc_XEditField.Visible="off";
                    app.CA_Acc_XEditFieldLabel.Visible="off";
                    app.CA_Acc_YEditField.Visible="off";
                    app.CA_Acc_YEditFieldLabel.Visible="off";
                    app.CA_Acc_ZEditField.Visible="off";
                    app.CA_Acc_ZEditFieldLabel.Visible="off";
                    app.CA_Pos_XEditField.Visible="off";
                    app.CA_Pos_XEditFieldLabel.Visible="off";
                    app.CA_Pos_YEditField.Visible="off";
                    app.CA_Pos_YEditFieldLabel.Visible="off";
                    app.CA_Pos_ZEditField.Visible="off";
                    app.CA_Pos_ZEditFieldLabel.Visible="off";
                    app.CA_Q_ElementsLabel.Visible="off";
                    app.CA_Vel_XEditField.Visible="off";
                    app.CA_Vel_XEditFieldLabel.Visible="off";
                    app.CA_Vel_YEditField.Visible="off";
                    app.CA_Vel_YEditFieldLabel.Visible="off";
                    app.CA_Vel_ZEditField.Visible="off";
                    app.CA_Vel_ZEditFieldLabel.Visible="off";
                    app.Switch.Visible="off";
                    app.SwitchLabel.Visible="off";
                    app.Spiral_Pos_XEditField.Value=app.Default_ekf(1,1);
                    app.Spiral_Pos_YEditField.Value=app.Default_ekf(2,2);
                    app.Spiral_Pos_ZEditField.Value=app.Default_ekf(3,3);
                    app.thetaEditField.Value=app.Default_ekf(4,4);
                    app.VelEditField.Value=app.Default_ekf(5,5);
                    app.OmegaEditField.Value=app.Default_ekf(6,6);
                case("IMM")
                    app.Spiral_Q_ElementsLabel.Visible="on";
                    app.Spiral_Pos_XEditField.Visible="on";
                    app.Spiral_Pos_XEditFieldLabel.Visible="on";
                    app.Spiral_Pos_YEditField.Visible="on";
                    app.Spiral_Pos_YEditFieldLabel.Visible="on";
                    app.Spiral_Pos_ZEditField.Visible="on";
                    app.Spiral_Pos_ZEditFieldLabel.Visible="on";
                    app.thetaEditField.Visible="on";
                    app.thetaEditFieldLabel.Visible="on";
                    app.VelEditField.Visible="on";
                    app.VelEditFieldLabel.Visible="on";
                    app.OmegaEditField.Visible="on";
                    app.OmegaEditFieldLabel.Visible="on";
                    app.CA_2_CAEditField.Visible="on";
                    app.CA_2_CAEditFieldLabel.Visible="on";
                    app.CA_2_SpiralEditField.Visible="on";
                    app.CA_2_SpiralEditFieldLabel.Visible="on";
                    app.Spiral_2_CAEditField.Visible="on";
                    app.Spiral_2_CAEditFieldLabel.Visible="on";
                    app.Spiral_2_SpiralEditField.Visible="on";
                    app.Spiral_2_SpiralEditFieldLabel.Visible="on";
                    app.TPMLabel.Visible="on";
                    app.CA_Acc_XEditField.Visible="on";
                    app.CA_Acc_XEditFieldLabel.Visible="on";
                    app.CA_Acc_YEditField.Visible="on";
                    app.CA_Acc_YEditFieldLabel.Visible="on";
                    app.CA_Acc_ZEditField.Visible="on";
                    app.CA_Acc_ZEditFieldLabel.Visible="on";
                    app.CA_Pos_XEditField.Visible="on";
                    app.CA_Pos_XEditFieldLabel.Visible="on";
                    app.CA_Pos_YEditField.Visible="on";
                    app.CA_Pos_YEditFieldLabel.Visible="on";
                    app.CA_Pos_ZEditField.Visible="on";
                    app.CA_Pos_ZEditFieldLabel.Visible="on";
                    app.CA_Q_ElementsLabel.Visible="on";
                    app.CA_Vel_XEditField.Visible="on";
                    app.CA_Vel_XEditFieldLabel.Visible="on";
                    app.CA_Vel_YEditField.Visible="on";
                    app.CA_Vel_YEditFieldLabel.Visible="on";
                    app.CA_Vel_ZEditField.Visible="on";
                    app.CA_Vel_ZEditFieldLabel.Visible="on";
                    app.Switch.Visible="on";
                    app.SwitchLabel.Visible="on";
                    app.CA_Pos_XEditField.Value=app.Default_KF(1,1);
                    app.CA_Pos_YEditField.Value=app.Default_KF(2,2);
                    app.CA_Pos_ZEditField.Value=app.Default_KF(3,3);
                    app.CA_Vel_XEditField.Value=app.Default_KF(4,4);
                    app.CA_Vel_YEditField.Value=app.Default_KF(5,5);
                    app.CA_Vel_ZEditField.Value=app.Default_KF(6,6);
                    app.CA_Acc_XEditField.Value=app.Default_KF(7,7);
                    app.CA_Acc_YEditField.Value=app.Default_KF(8,8);
                    app.CA_Acc_ZEditField.Value=app.Default_KF(9,9);
                    app.Spiral_Pos_XEditField.Value=app.Default_ekf_kf(1,1);
                    app.Spiral_Pos_YEditField.Value=app.Default_ekf_kf(2,2);
                    app.Spiral_Pos_ZEditField.Value=app.Default_ekf_kf(3,3);
                    app.thetaEditField.Value=app.Default_ekf_kf(4,4);
                    app.VelEditField.Value=app.Default_ekf_kf(5,5);
                    app.OmegaEditField.Value=app.Default_ekf_kf(6,6);
                    app.CA_2_CAEditField.Value=app.Default_KF_EKF_TPM(1,1);
                    app.CA_2_SpiralEditField.Value=app.Default_KF_EKF_TPM(1,2);
                    app.Spiral_2_SpiralEditField.Value=app.Default_KF_EKF_TPM(2,1);
                    app.Spiral_2_CAEditField.Value=app.Default_KF_EKF_TPM(2,2);
            end
        end

        % Button pushed function: ResultsAnalysisButton
        function Run(app, event)
           qCA_vec = [app.CA_Pos_XEditField.Value;app.CA_Pos_YEditField.Value;app.CA_Pos_ZEditField.Value; ...
                      app.CA_Vel_XEditField.Value;app.CA_Vel_YEditField.Value;app.CA_Vel_ZEditField.Value; ...
                       app.CA_Acc_XEditField.Value;app.CA_Acc_YEditField.Value;app.CA_Acc_ZEditField.Value];
           QCA = diag(qCA_vec);                
            % Q (Spiral EKF)
            qs_vec = [app.Spiral_Pos_XEditField.Value;app.Spiral_Pos_YEditField.Value;app.Spiral_Pos_ZEditField.Value; ...
                      app.thetaEditField.Value;app.VelEditField.Value;app.OmegaEditField.Value];
            Qs = diag(qs_vec);
            % TPM
            TPM = [app.CA_2_CAEditField.Value,app.CA_2_SpiralEditField.Value;   
                   app.Spiral_2_CAEditField.Value,app.Spiral_2_SpiralEditField.Value];          
            switch app.Knob.Value
                case "IMM"
                    if app.Switch.Value=="KF"
                        app.Run_IMM_KF_Spiral_Analysis(QCA,Qs,TPM);
                    end
                    if app.Switch.Value=="UKF"
                        app.Run_IMM_UKF_Spiral_Analysis(QCA,Qs,TPM);
                    end
                
                case("Constant Accelaration")
                    if app.Switch.Value=="KF"
                       app.Run_CA_KF_Analysis(QCA);
                    end
                    if app.Switch.Value=="UKF"
                        app.RUN_CA_UKF_Analysis(QCA);
                    end
                
                case("Spiral")
                    app.RUN_Spiral_EKF_Analysis(Qs);
            
            end


        end

        % Value changed function: Switch
        function Switch_ca(app, event)
            value = app.Switch.Value;
            switch value
                case "UKF"
                    app.CA_Pos_XEditField.Value=app.Default_UKF(1,1);
                    app.CA_Pos_YEditField.Value=app.Default_UKF(2,2);
                    app.CA_Pos_ZEditField.Value=app.Default_UKF(3,3);
                    app.CA_Vel_XEditField.Value=app.Default_UKF(4,4);
                    app.CA_Vel_YEditField.Value=app.Default_UKF(5,5);
                    app.CA_Vel_ZEditField.Value=app.Default_UKF(6,6);
                    app.CA_Acc_XEditField.Value=app.Default_UKF(7,7);
                    app.CA_Acc_YEditField.Value=app.Default_UKF(8,8);
                    app.CA_Acc_ZEditField.Value=app.Default_UKF(9,9);
                case "KF"
                    app.CA_Pos_XEditField.Value=app.Default_KF(1,1);
                    app.CA_Pos_YEditField.Value=app.Default_KF(2,2);
                    app.CA_Pos_ZEditField.Value=app.Default_KF(3,3);
                    app.CA_Vel_XEditField.Value=app.Default_KF(4,4);
                    app.CA_Vel_YEditField.Value=app.Default_KF(5,5);
                    app.CA_Vel_ZEditField.Value=app.Default_KF(6,6);
                    app.CA_Acc_XEditField.Value=app.Default_KF(7,7);
                    app.CA_Acc_YEditField.Value=app.Default_KF(8,8);
                    app.CA_Acc_ZEditField.Value=app.Default_KF(9,9);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'IMM', 'Constant Accelaration', 'Spiral'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @Select_model, true);
            app.Knob.Value = 'IMM';
            app.CreateTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.CreateTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @Create_traj, true);
            app.CreateTrajectoryButton.Text = 'Create Trajectory';
            app.ResultsAnalysisButton = uibutton(app.UIFigure, 'push');
            app.ResultsAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.ResultsAnalysisButton.Text = 'Results Analysis';
            app.CA_Q_ElementsLabel = uilabel(app.UIFigure);
            app.CA_Q_ElementsLabel.Text = 'CA_Q_Elements';
            app.Spiral_Q_ElementsLabel = uilabel(app.UIFigure);
            app.Spiral_Q_ElementsLabel.Text = 'Spiral_Q_Elements';
            app.TPMLabel = uilabel(app.UIFigure);
            app.TPMLabel.Text = 'TPM';
            app.CA_Pos_XEditFieldLabel = uilabel(app.UIFigure);
            app.CA_Pos_XEditFieldLabel.Text = 'CA_Pos_X';
            app.CA_Pos_XEditField = uieditfield(app.UIFigure, 'numeric');
            app.CA_Pos_XEditField.Value = 1.6929e-06;
            app.CA_Pos_YEditFieldLabel = uilabel(app.UIFigure);
            app.CA_Pos_YEditFieldLabel.Text = 'CA_Pos_Y';
            app.CA_Pos_YEditField = uieditfield(app.UIFigure, 'numeric');
            app.CA_Pos_YEditField.Value = 1.1685e-06;
            app.CA_Pos_ZEditFieldLabel = uilabel(app.UIFigure);
            app.CA_Pos_ZEditFieldLabel.Text = 'CA_Pos_Z';
            app.CA_Pos_ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.CA_Pos_ZEditField.Value = 5.7749e-06;
            app.CA_Vel_XEditFieldLabel = uilabel(app.UIFigure);
            app.CA_Vel_XEditFieldLabel.Text = 'CA_Vel_X';
            app.CA_Vel_XEditField = uieditfield(app.UIFigure, 'numeric');
            app.CA_Vel_XEditField.Value = 0.1491;
            app.CA_Vel_YEditFieldLabel = uilabel(app.UIFigure);
            app.CA_Vel_YEditFieldLabel.Text = 'CA_Vel_Y';
            app.CA_Vel_YEditField = uieditfield(app.UIFigure, 'numeric');
            app.CA_Vel_YEditField.Value = 0.1514;
            app.CA_Vel_ZEditFieldLabel = uilabel(app.UIFigure);
            app.CA_Vel_ZEditFieldLabel.Text = 'CA_Vel_Z';
            app.CA_Vel_ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.CA_Vel_ZEditField.Value = 0.0422;
            app.CA_Acc_XEditFieldLabel = uilabel(app.UIFigure);
            app.CA_Acc_XEditFieldLabel.Text = 'CA_Acc_X';
            app.CA_Acc_XEditField = uieditfield(app.UIFigure, 'numeric');
            app.CA_Acc_XEditField.Value = 4.7873e-06;
            app.CA_Acc_YEditFieldLabel = uilabel(app.UIFigure);
            app.CA_Acc_YEditFieldLabel.Text = 'CA_Acc_Y';
            app.CA_Acc_YEditField = uieditfield(app.UIFigure, 'numeric');
            app.CA_Acc_YEditField.Value = 3.5685e-05;
            app.CA_Acc_ZEditFieldLabel = uilabel(app.UIFigure);
            app.CA_Acc_ZEditFieldLabel.Text = 'CA_Acc_Z';
            app.CA_Acc_ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.CA_Acc_ZEditField.Value = 2.4633e-07;
            app.Spiral_Pos_XEditFieldLabel = uilabel(app.UIFigure);
            app.Spiral_Pos_XEditFieldLabel.Text = 'Spiral_Pos_X';
            app.Spiral_Pos_XEditField = uieditfield(app.UIFigure, 'numeric');
            app.Spiral_Pos_XEditField.Value = 0.3809;
            app.Spiral_Pos_YEditFieldLabel = uilabel(app.UIFigure);
            app.Spiral_Pos_YEditFieldLabel.Text = 'Spiral_Pos_Y';
            app.Spiral_Pos_YEditField = uieditfield(app.UIFigure, 'numeric');
            app.Spiral_Pos_YEditField.Value = 0.5186;
            app.Spiral_Pos_ZEditFieldLabel = uilabel(app.UIFigure);
            app.Spiral_Pos_ZEditFieldLabel.Text = 'Spiral_Pos_Z';
            app.Spiral_Pos_ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.Spiral_Pos_ZEditField.Value = 0.0576;
            app.thetaEditFieldLabel = uilabel(app.UIFigure);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.thetaEditField.Value = 4.7967e-08;
            app.VelEditFieldLabel = uilabel(app.UIFigure);
            app.VelEditFieldLabel.Text = 'Vel';
            app.VelEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelEditField.Value = 1.2724e-08;
            app.OmegaEditFieldLabel = uilabel(app.UIFigure);
            app.OmegaEditFieldLabel.Text = 'Omega';
            app.OmegaEditField = uieditfield(app.UIFigure, 'numeric');
            app.OmegaEditField.Value = 6.3331e-08;
            app.CA_2_CAEditFieldLabel = uilabel(app.UIFigure);
            app.CA_2_CAEditFieldLabel.Text = 'CA_2_CA';
            app.CA_2_CAEditField = uieditfield(app.UIFigure, 'numeric');
            app.CA_2_CAEditField.Value = 0.95;
            app.Spiral_2_SpiralEditFieldLabel = uilabel(app.UIFigure);
            app.Spiral_2_SpiralEditFieldLabel.Text = 'Spiral_2_Spiral';
            app.Spiral_2_SpiralEditField = uieditfield(app.UIFigure, 'numeric');
            app.Spiral_2_SpiralEditField.Value = 0.45;
            app.CA_2_SpiralEditFieldLabel = uilabel(app.UIFigure);
            app.CA_2_SpiralEditFieldLabel.Text = 'CA_2_Spiral';
            app.CA_2_SpiralEditField = uieditfield(app.UIFigure, 'numeric');
            app.CA_2_SpiralEditField.Value = 0.05;
            app.Spiral_2_CAEditFieldLabel = uilabel(app.UIFigure);
            app.Spiral_2_CAEditFieldLabel.Text = 'Spiral_2_CA';
            app.Spiral_2_CAEditField = uieditfield(app.UIFigure, 'numeric');
            app.Spiral_2_CAEditField.Value = 0.55;
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.Switch.Items = {'KF', 'UKF'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @Switch_ca, true);
            app.Switch.Value = 'KF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Drone_Tracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end